classdef GUI_GlobalBioIm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GlobalBioImGUIUIFigure       matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Main                         matlab.ui.container.Tab
        LabelGlobalBioIm             matlab.ui.control.Label
        ButtonSaveMainPanel          matlab.ui.control.Button
        ButtonSelectPipeline         matlab.ui.control.Button
        DropDownPipeline             matlab.ui.control.DropDown
        PipelineEditFieldLabel       matlab.ui.control.Label
        EditFieldPipeline            matlab.ui.control.EditField
        DescriptionTextAreaLabel     matlab.ui.control.Label
        TextAreaDescriptionPipeline  matlab.ui.control.TextArea
        LabelCopyright               matlab.ui.control.Label
        ForwardModel                 matlab.ui.container.Tab
        TextAreaForwardModel         matlab.ui.control.TextArea
        LabelTextArea                matlab.ui.control.Label
        ButtonSelectLinOp            matlab.ui.control.Button
        ButtonSeeDocLinOp            matlab.ui.control.Button
        OperatorLabel                matlab.ui.control.Label
        DropDownLinOp                matlab.ui.control.DropDown
        PanelHierarchyLinOp          matlab.ui.container.Panel
        NotationLinOp1               matlab.ui.control.Label
        NameLinOp1                   matlab.ui.control.Label
        ButtonSetParamsLinOp1        matlab.ui.control.Button
        ButtonUpLinOp1               matlab.ui.control.Button
        ButtonDownLinOp1             matlab.ui.control.Button
        ButtonDeleteLinOp1           matlab.ui.control.Button
        NotationLinOp2               matlab.ui.control.Label
        NameLinOp2                   matlab.ui.control.Label
        ButtonSetParamsLinOp2        matlab.ui.control.Button
        ButtonUpLinOp2               matlab.ui.control.Button
        ButtonDownLinOp2             matlab.ui.control.Button
        ButtonDeleteLinOp2           matlab.ui.control.Button
        NotationLinOp3               matlab.ui.control.Label
        NameLinOp3                   matlab.ui.control.Label
        ButtonSetParamsLinOp3        matlab.ui.control.Button
        ButtonUpLinOp3               matlab.ui.control.Button
        ButtonDownLinOp3             matlab.ui.control.Button
        ButtonDeleteLinOp3           matlab.ui.control.Button
        NotationLinOp4               matlab.ui.control.Label
        NameLinOp4                   matlab.ui.control.Label
        ButtonSetParamsLinOp4        matlab.ui.control.Button
        ButtonUpLinOp4               matlab.ui.control.Button
        ButtonDownLinOp4             matlab.ui.control.Button
        ButtonDeleteLinOp4           matlab.ui.control.Button
        NotationLinOp5               matlab.ui.control.Label
        NameLinOp5                   matlab.ui.control.Label
        ButtonSetParamsLinOp5        matlab.ui.control.Button
        ButtonUpLinOp5               matlab.ui.control.Button
        ButtonDownLinOp5             matlab.ui.control.Button
        ButtonDeleteLinOp5           matlab.ui.control.Button
        NotationLinOp6               matlab.ui.control.Label
        NameLinOp6                   matlab.ui.control.Label
        ButtonSetParamsLinOp6        matlab.ui.control.Button
        ButtonUpLinOp6               matlab.ui.control.Button
        ButtonDownLinOp6             matlab.ui.control.Button
        ButtonDeleteLinOp6           matlab.ui.control.Button
        ButtonInfoLinOp              matlab.ui.control.Button
        ButtonCheckFwd               matlab.ui.control.Button
        ButtonSaveFwdPanel           matlab.ui.control.Button
        CostFunction                 matlab.ui.container.Tab
        TextArea_CostFunction        matlab.ui.control.TextArea
        CostFunctionLabel            matlab.ui.control.Label
        Button_Add_DataFidelity      matlab.ui.control.Button
        Button_SeeDocDataFidelity    matlab.ui.control.Button
        DatafidelityLabel            matlab.ui.control.Label
        DropDown_SelectDataFidelity  matlab.ui.control.DropDown
        FMPanel_CostFunction         matlab.ui.container.Panel
        Button_Add_Regul             matlab.ui.control.Button
        Button_SeeDocRegul           matlab.ui.control.Button
        RegularizerLabel             matlab.ui.control.Label
        DropDown_Select_Regul        matlab.ui.control.DropDown
        AddPositivityConstraintCheckBox  matlab.ui.control.CheckBox
        FMPanel_CostFunction_2       matlab.ui.container.Panel
        Button                       matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        ButtonSaveCostPanel          matlab.ui.control.Button
        Optimization                 matlab.ui.container.Tab
        FMPanel_CostFunction_3       matlab.ui.container.Panel
        Button_Add_Regul_2           matlab.ui.control.Button
        Button_SeeDoc_Regul_2        matlab.ui.control.Button
        AlgorithmLabel               matlab.ui.control.Label
        DropDown_Select_Regul_2      matlab.ui.control.DropDown
        Button_4                     matlab.ui.control.Button
        RUNButton                    matlab.ui.control.Button
        GenerateScriptButton         matlab.ui.control.Button
        RecapofAllSelectedParametersButton  matlab.ui.control.Button
        DisplaythecostfunctionevolutionCheckBox  matlab.ui.control.CheckBox
        FMPanel_CostFunction_4       matlab.ui.container.Panel
        ButtonSaveOptiPanel          matlab.ui.control.Button
    end

    % ======================================
    % Programatically added private Properties
    % ======================================
    properties (Access = private)
        
        % Global properties
        doc_weblink = 'biomedical-imaging-group.github.io/GlobalBioIm/'; % Link to online documentation
        
        % Properties for ForwardModel panel
        maxLinOp         = 6;      % maximum number of concatenable linOps
        
        % Properties for CostFunction panel
        maxRegul         = 3;      % maximum number of regularization terms
        costText         = [];     % info displayed in text area for user
        dfListGUI        = {'Quadratic (L2)', 'Kullback-Leibler (KL)'}; % proposed data-fidelity terms (in GUI)
        dfListCode       = {'L2', 'KullLeib'};                          % corresponding data-fidelity terms (in code)
        regListGUI       = {'Tikhonov (Tikh)', 'Total-Variation (TV)', 'Hessian-Schatten (HS)'}; % proposed regularization terms (in GUI)
        regCostListCode  = {'L2', 'MixNorm21', 'MixNormSchatt1'}; % corresponding regularization costs (in code)
        regLinOpListCode = {'Grad', 'Grad', 'Hess'}; % corresponding regularization operators (in code)
        
        % Information for display
        pos_left      = 50;
        pos_bott_init = 325;
        incr_height   = 50;
        def_width     = 30;
        def_height    = 20;
        
    end
    
    % ======================================
    % Programatically added public Properties
    % ======================================
    properties (Access = public)

        LinOpCell={}    % Cell array containing the LinOps (ForwardModel panel)
                        % Each element of this cell contains a structure with fields
                        % - name     (name of the LinOp)
                        % - notation (letter used to represent the LinOp)
                        % - call     (command line used to instanciate the LinOp)
                        % - parameters (cell with fields: name/type/val)

        SetParamsFigs={}
        nbSetParamsFigs=0;   % Number of opened Set Parameters Figures
        
        DFstruct = struct(); % Structure containing the data-fidelity term (CostFunction panel)
        
        RegulCell={}   % Cell array containing the regularizations (CostFunction panel)
        
        logwd=[]       % Log window
        
        script=struct('header',{{'%----------------------------------------------'}}, ...
            'params',{{'%% Load/Read Parameters'}}, ...
            'linop',{{'%% Instanciate the Forward Model'}}, ...
            'cost',{{'%% Instanciate the Cost function'}}, ...
            'algo',{{'%% Instanciate and Run the Optimization method'}});
        
        pipeline    % structure with fields
                    %    -name  (name of the pipeline)
                    %    -dscpt (description of the pipeline)
    end
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    % ======================================
    %           COMMON METHODS
    % ======================================
    methods (Access = private)    
        % -- Open Log window
        function OpenLog(app,msg)
            app.logwd.fig = uifigure;
            app.logwd.fig.Position =app.GlobalBioImGUIUIFigure.Position  +  [0 app.GlobalBioImGUIUIFigure.Position(4)/3 0 -app.GlobalBioImGUIUIFigure.Position(4)*2/3];
            app.logwd.fig.Name  = 'Log';
            app.logwd.TextArea = uitextarea(app.logwd.fig);
            app.logwd.TextArea.Value = msg;
            app.logwd.TextArea.FontColor='red';
            app.logwd.TextArea.Position = [10 10 app.logwd.fig.Position(3)-20 app.logwd.fig.Position(4)-20];
            app.logwd.TextArea.Editable = 'off';
        end        
    end
    
    % ======================================
    %          MAIN PANEL METHODS
    % ======================================
    methods (Access = private)
        % -- Set list of Pipelines in dropdown menu
        function ListOfPipelines = GetListOfAvailablePipelines(app)
            dirList=struct2cell(dir('pipelines/*mat'));
            dirList=dirList(1,:);
            ListOfPipelines=strrep(dirList,'.mat','');
        end
    
        % -- Initialize Main Panel
        function InitializeMainPanel(app)
            ListOfPipelines = GetListOfAvailablePipelines(app);
            if isempty(ListOfPipelines)
                ListOfPipelines = {'Select a pipeline','New','Browse'};
            else
                ListOfPipelines =  ['Select a pipeline','New','Browse',{ListOfPipelines{:}}];
            end
            app.DropDownPipeline.Items = ListOfPipelines;         
        end
    end
 
    % ======================================
    %      FORWARD MODEL PANEL METHODS
    % ======================================
    methods (Access = private)
        % -- Set list of linOps in dropdown menu
        function ListOfLinOpsStr = GetListOfAvailableLinOps(app)
            dirList1 = struct2cell(dir('../LinOp/LinOp*.m'));
            dirList2 = struct2cell(dir('../LinOp/SelectorLinOps/LinOp*.m'));
            dirList = [dirList1 dirList2];
            idx=[];
            for ii=1:length(dirList)
                if ~isempty(regexp(fileread(dirList{1,ii}),'GUI-Header','once'))
                    idx=[idx,ii];
                end
            end
            dirList=dirList(1,idx);
            ListOfLinOpsStr = strrep(strrep(dirList,'.m',''),'LinOp','');
        end
       
        % -- Initialize Forward Model Panel
        function InitializeForwardModelPanel(app)
            % Get the list of LinOps
            ListOfLinOpsStr = GetListOfAvailableLinOps(app);
            
            % Display dropdown GUI list
            app.DropDownLinOp.Items = ListOfLinOpsStr;
            
            % Hide hierarchy of linOps
            HideHierarchyLinOps(app);
            
            % Set Tags
            app.ButtonSeeDocLinOp.Tag = 'LinOp'; 
            app.ButtonDeleteLinOp1.Tag='LinOp1';app.ButtonDeleteLinOp2.Tag='LinOp2';app.ButtonDeleteLinOp3.Tag='LinOp3';
            app.ButtonDeleteLinOp4.Tag='LinOp4';app.ButtonDeleteLinOp5.Tag='LinOp5';app.ButtonDeleteLinOp6.Tag='LinOp6';
            app.ButtonUpLinOp1.Tag='LinOp1';app.ButtonUpLinOp2.Tag='LinOp2';app.ButtonUpLinOp3.Tag='LinOp3';
            app.ButtonUpLinOp4.Tag='LinOp4';app.ButtonUpLinOp5.Tag='LinOp5';app.ButtonUpLinOp6.Tag='LinOp6';
            app.ButtonDownLinOp1.Tag='LinOp1';app.ButtonDownLinOp2.Tag='LinOp2';app.ButtonDownLinOp3.Tag='LinOp3';
            app.ButtonDownLinOp4.Tag='LinOp4';app.ButtonDownLinOp5.Tag='LinOp5';app.ButtonDownLinOp6.Tag='LinOp6';
            app.ButtonSetParamsLinOp1.Tag='LinOp1';app.ButtonSetParamsLinOp2.Tag='LinOp2';app.ButtonSetParamsLinOp3.Tag='LinOp3';
            app.ButtonSetParamsLinOp4.Tag='LinOp4';app.ButtonSetParamsLinOp5.Tag='LinOp5';app.ButtonSetParamsLinOp6.Tag='LinOp6';
        end
    
        % -- Hide hierarchy of linOps
        function HideHierarchyLinOps(app)
            app.NotationLinOp1.Visible='off';app.NotationLinOp2.Visible='off';app.NotationLinOp3.Visible='off';
            app.NotationLinOp4.Visible='off';app.NotationLinOp5.Visible='off';app.NotationLinOp6.Visible='off';
            app.NameLinOp1.Visible='off';app.NameLinOp2.Visible='off';app.NameLinOp3.Visible='off';
            app.NameLinOp4.Visible='off';app.NameLinOp5.Visible='off';app.NameLinOp6.Visible='off';
            app.ButtonSetParamsLinOp1.Visible='off';app.ButtonSetParamsLinOp2.Visible='off';app.ButtonSetParamsLinOp3.Visible='off';
            app.ButtonSetParamsLinOp4.Visible='off';app.ButtonSetParamsLinOp5.Visible='off';app.ButtonSetParamsLinOp6.Visible='off';
            app.ButtonUpLinOp1.Visible='off';app.ButtonUpLinOp2.Visible='off';app.ButtonUpLinOp3.Visible='off';
            app.ButtonUpLinOp4.Visible='off';app.ButtonUpLinOp5.Visible='off';app.ButtonUpLinOp6.Visible='off';
            app.ButtonDownLinOp1.Visible='off';app.ButtonDownLinOp2.Visible='off';app.ButtonDownLinOp3.Visible='off';
            app.ButtonDownLinOp4.Visible='off';app.ButtonDownLinOp5.Visible='off';app.ButtonDownLinOp6.Visible='off';
            app.ButtonDeleteLinOp1.Visible='off';app.ButtonDeleteLinOp2.Visible='off';app.ButtonDeleteLinOp3.Visible='off';
            app.ButtonDeleteLinOp4.Visible='off';app.ButtonDeleteLinOp5.Visible='off';app.ButtonDeleteLinOp6.Visible='off';
        end
        
        % -- Show hierarchy of linOps
        function ShowHierarchyLinOps(app)
            % Number of LinOps
            nLinOps = length(app.LinOpCell);
            
            % Loop over all the selected LinOps
            LinOpText = 'H = ';
            for idx=1:nLinOps
                % Notation Field
                app=setfield(app,['NotationLinOp',num2str(idx)],'Visible','on');
                app=setfield(app,['NotationLinOp',num2str(idx)],'Text',[app.LinOpCell{idx}.notation, ' : ']);
                % Name Field
                app=setfield(app,['NameLinOp',num2str(idx)],'Visible','on');
                app=setfield(app,['NameLinOp',num2str(idx)],'Text',['LinOp' ,app.LinOpCell{idx}.name]);
                % Set parameter button
                app=setfield(app,['ButtonSetParamsLinOp',num2str(idx)],'Visible','on');
                % Up button
                if idx > 1
                    app=setfield(app,['ButtonUpLinOp',num2str(idx)],'Visible','on');
                end
                % Down button
                if idx < nLinOps
                    app=setfield(app,['ButtonDownLinOp',num2str(idx)],'Visible','on');
                end
                % Delete button
                app=setfield(app,['ButtonDeleteLinOp',num2str(idx)],'Visible','on');                                
                % Update text for concatenation of linOps
                LinOpText = [LinOpText app.LinOpCell{idx}.notation];
            end
            
            % Update text for concatenation of linOps
            app.TextAreaForwardModel.Value = LinOpText;            
        end
        
        % -- Button pushed function: Button_ApplyChangesLinOpPushed
        function ButtonPushed_ApplyChanges(app,event)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with 
            switch tag
                case 'LinOp'
                    pp=app.LinOpCell{idx}.parameters;
                    nButtom=length(app.LinOpCell);
            end
            % Check if syntax error
            if (~isempty(app.logwd) && ishandle(app.logwd.fig)), close(app.logwd.fig); end
            err=0;msg={};
            for ii=1:length(pp)
                typ=pp{ii}.type;
                switch  typ
                    case 'vecInt'
                        tmp=arrayfun(@(x) [num2str(x),' '],str2num(pp{ii}.val),'UniformOutput',false);
                        tmp=horzcat(tmp{:}); tmp=tmp(1:end-1);
                        if ~isempty(pp{ii}.val) &&  (~strcmp(tmp,pp{ii}.val) || all(mod(str2num(pp{ii}.val),1)~=0))
                            err=1;
                            msg{length(msg)+1}=['Wrong entry for parameter ',pp{ii}.name];
                            msg{length(msg)+1}=' --> A sequence of integers separated by a space is required (e.g. 512 512)';
                            app.SetParamsFigs{idx}.param{ii}.label.FontColor='red';
                        else
                            app.SetParamsFigs{idx}.param{ii}.label.FontColor='black';
                        end
                end
            end
            % If no errors make the figure visible off
            if err==0
                app.SetParamsFigs{idx}.fig.Visible='off';
                app.nbSetParamsFigs=app.nbSetParamsFigs-1; % Increase the counter of opened Set Params Figs
                if app.nbSetParamsFigs==0
                    for ii=1:nButtom
                        app = setfield(app,['ButtonDelete',tag,num2str(ii)],'Enable','on');
                        app = setfield(app,['ButtonUp',tag,num2str(ii)],'Enable','on');
                        app = setfield(app,['ButtonDown',tag,num2str(ii)],'Enable','on');
                    end
                     app = setfield(app,['ButtonSelect',tag],'Enable','on');
                end
                app = setfield(app,['ButtonSetParams',event.Source.Tag],'Enable','on');
            else
                OpenLog(app,msg);
            end
        end
        
        % -- Value Changed function: ValueChanged_SetParamsFigEdit
        function ValueChanged_SetParamsFigEdit(app,event,ii)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with
            switch tag
                case 'LinOp'
                    app.LinOpCell{idx}.parameters{ii}.val=app.SetParamsFigs{idx}.param{ii}.EditField.Value;
                case 'DF'
                    
                case 'Regul'
                    
            end
        end
    
        % -- Button pushed function: ButtonPushed_SetParamsFigBrowse
        function ButtonPushed_SetParamsFigBrowse(app,event,ii)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with
            [file,path]=uigetfile();
            if file~=0
                switch tag
                    case 'LinOp'
                        app.LinOpCell{idx}.parameters{ii}.val=[path,file];
                        app.SetParamsFigs{idx}.param{ii}.TextArea.Value=[path,file];
                    case 'DF'
                        
                    case 'Regul'
                        
                end  
            end
        end       
    end
    
    % ======================================
    %           COST FUNCTION PANEL
    % ======================================
    methods (Access = private)
        % -- Initialize Cost Function Panel
        function InitializeCostFunctionPanel(app)
            
            % display both dropdown GUI lists
            app.DropDown_SelectDataFidelity.Items = app.dfListGUI;
            app.DropDown_Select_Regul.Items = app.regListGUI;
            
            % Force the user to select a data-fidelity before a regularization
            app.Button_Add_Regul.Enable                = 'off';
            app.Button_SeeDocRegul.Enable              = 'off';
            app.DropDown_Select_Regul.Enable           = 'off';
            app.AddPositivityConstraintCheckBox.Enable = 'off';
            
            % Set Tags
            app.Button_SeeDocDataFidelity.Tag = 'DF';
            app.Button_SeeDocRegul.Tag = 'Regul';
        end
              
        % -- Create data-fidelity term
        function createDataFidelity(app)
            
            % SHOULD BE A CALL TO A GENERIC FUNCTION (OR MORE)
            
            app.TextArea_CostFunction.Value = ['C(x) = ' app.DFstruct.notation '(x,y)'];
            
            % Position info
            pos_bott      = app.pos_bott_init+app.incr_height*0.85;
            
            % Add Regul name info (for the selection box below)
            app.DFstruct.label          = uilabel(app.CostFunction);
            app.DFstruct.label.Text     = [app.DFstruct.name, ' data-fidelity'];
            app.DFstruct.label.Position = [app.pos_left+40 pos_bott app.def_width*8 app.def_height];
            
            % Create delete button (for the selection box below)
            app.DFstruct.butDel                 = uibutton(app.CostFunction, 'push');
            app.DFstruct.butDel.Text            = '';
            app.DFstruct.butDel.Icon            = 'trashIcon.png';
            app.DFstruct.butDel.Position        = [app.pos_left+340 pos_bott app.def_width*0.8 app.def_height];
            app.DFstruct.butDel.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_DelDFButtonPushed(app), true);
            
            % Create parameters button
            app.DFstruct.butProp                 = uibutton(app.CostFunction, 'push');
            app.DFstruct.butProp.Text            = 'Set Parameters';
            app.DFstruct.butProp.FontWeight      = 'bold';
            app.DFstruct.butProp.Position        = [app.pos_left+230 pos_bott app.def_width*3.2 app.def_height];
            app.DFstruct.butProp.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_PropDFButtonPushed(app), true);
            
            % Create label of drop-down component (for the selection box below)
            app.DFstruct.ddlabel             = uilabel(app.CostFunction);
            app.DFstruct.ddlabel.Position    = [app.pos_left pos_bott app.def_width*1.5 app.def_height];
            app.DFstruct.ddlabel.FontWeight  = 'bold';
            app.DFstruct.ddlabel.FontSize    = 16;
            app.DFstruct.ddlabel.Text        = app.DFstruct.notation;
            
        end
                
        % -- Button pushed function: Button_PropDFButtonPushed
        function Button_PropDFButtonPushed(app)
            
            % SHOULD BE A CALL TO A GENERIC FUNCTION
            
            % disable button
            app.DFstruct.butProp.Enable='off';
            app.Button_Add_DataFidelity.Enable='off';
            app.DFstruct.butDel.Enable='off';
            app.DFstruct.butUp.Enable='off';
            app.DFstruct.butDown.Enable='off';
            
            % Create Figure
            hh=app.incr_height*(length(app.DFstruct.parameters)+1);
            app.DFstruct.SetPropGUI.fig = uifigure;
            app.DFstruct.SetPropGUI.fig.Position  = app.GlobalBioImGUIUIFigure.Position  +  [app.GlobalBioImGUIUIFigure.Position(3) app.GlobalBioImGUIUIFigure.Position(4)-hh 0 -app.GlobalBioImGUIUIFigure.Position(4)+hh];
            app.DFstruct.SetPropGUI.fig.Name  = 'Set Parameters';
            
            % Create Name
            app.DFstruct.SetPropGUI.title = uilabel(app.DFstruct.SetPropGUI.fig);
            app.DFstruct.SetPropGUI.title.FontSize = 16;
            app.DFstruct.SetPropGUI.title.FontWeight = 'bold';
            app.DFstruct.SetPropGUI.title.Position = [19 hh-45 428 38];
            app.DFstruct.SetPropGUI.title.Text = app.DFstruct.label.Text;
            
            % Create Apply Changes button
            app.DFstruct.SetPropGUI.butApplyChanges = uibutton(app.DFstruct.SetPropGUI.fig, 'push');
            app.DFstruct.SetPropGUI.butApplyChanges.Position = [350 hh-35 100 20];
            app.DFstruct.SetPropGUI.butApplyChanges.Text = 'Apply Changes';
            app.DFstruct.SetPropGUI.butApplyChanges.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_ApplyChangesDFPushed(app), true);
            
            % Create See Doc  button
            app.DFstruct.SetPropGUI.butSeeDoc = uibutton(app.DFstruct.SetPropGUI.fig, 'push');
            app.DFstruct.SetPropGUI.butSeeDoc.Position = [280 hh-35 50 20];
            app.DFstruct.SetPropGUI.butSeeDoc.Text = 'Doc.';
            app.DFstruct.SetPropGUI.butSeeDoc.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_SeeDFDocButtonPushed(app), true);
            
            % Display parameters selection
            for ii=1:length(app.DFstruct.parameters)
                pos_bott      = hh-(ii+0.5)*app.incr_height;
                % Info Button_
                app.DFstruct.SetPropGUI.param{ii}.info = uibutton(app.DFstruct.SetPropGUI.fig, 'push');
                app.DFstruct.SetPropGUI.param{ii}.info.Icon = 'infoIcon.png';
                app.DFstruct.SetPropGUI.param{ii}.info.Tooltip = {app.DFstruct.parameters{ii}.info};
                app.DFstruct.SetPropGUI.param{ii}.info.Position = [30 pos_bott 20 app.def_height];
                app.DFstruct.SetPropGUI.param{ii}.info.Text = '';
                typ=app.DFstruct.parameters{ii}.type;
                switch  typ
                    case 'file'
                        app.DFstruct.SetPropGUI.param{ii}.label = uilabel(app.DFstruct.SetPropGUI.fig);
                        app.DFstruct.SetPropGUI.param{ii}.label.HorizontalAlignment = 'left';
                        app.DFstruct.SetPropGUI.param{ii}.label.Position = [60 pos_bott 80 app.def_height];
                        app.DFstruct.SetPropGUI.param{ii}.label.Text = [app.DFstruct.parameters{ii}.name,' : '];
                        app.DFstruct.SetPropGUI.param{ii}.TextArea = uitextarea(app.DFstruct.SetPropGUI.fig);
                        app.DFstruct.SetPropGUI.param{ii}.TextArea.Position = [150 pos_bott 220 app.def_height];
                        text=app.DFstruct.parameters{ii}.val;
                        if isempty(text), text='Select a file (.png,.jpg,.tif,.mat)'; end
                        app.DFstruct.SetPropGUI.param{ii}.TextArea.Value=text;
                        app.DFstruct.SetPropGUI.param{ii}.butBrowse = uibutton(app.DFstruct.SetPropGUI.fig, 'push');
                        app.DFstruct.SetPropGUI.param{ii}.butBrowse.Text = 'Browse';
                        app.DFstruct.SetPropGUI.param{ii}.butBrowse.Position = [380 pos_bott 60 app.def_height];
                        app.DFstruct.SetPropGUI.param{ii}.butBrowse.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_BrowseSetPropDFPushed(app,ii), true);
                    case 'vecInt'
                        app.DFstruct.SetPropGUI.param{ii}.label = uilabel(app.DFstruct.SetPropGUI.fig);
                        app.DFstruct.SetPropGUI.param{ii}.label.HorizontalAlignment = 'left';
                        app.DFstruct.SetPropGUI.param{ii}.label.Position = [60 pos_bott 80 app.def_height];
                        app.DFstruct.SetPropGUI.param{ii}.label.Text = [app.DFstruct.parameters{ii}.name,' : '];
                        app.DFstruct.SetPropGUI.param{ii}.openBracket = uilabel(app.DFstruct.SetPropGUI.fig);
                        app.DFstruct.SetPropGUI.param{ii}.openBracket.Position = [140 pos_bott-5 10 app.def_height*1.8];
                        app.DFstruct.SetPropGUI.param{ii}.openBracket.Text = '[';
                        app.DFstruct.SetPropGUI.param{ii}.openBracket.FontSize = 30;
                        app.DFstruct.SetPropGUI.param{ii}.closeBracket = uilabel(app.DFstruct.SetPropGUI.fig);
                        app.DFstruct.SetPropGUI.param{ii}.closeBracket.Position = [232 pos_bott-5 10 app.def_height*1.8];
                        app.DFstruct.SetPropGUI.param{ii}.closeBracket.Text = ']';
                        app.DFstruct.SetPropGUI.param{ii}.closeBracket.FontSize = 30;
                        app.DFstruct.SetPropGUI.param{ii}.EditField = uieditfield(app.DFstruct.SetPropGUI.fig,'text');
                        app.DFstruct.SetPropGUI.param{ii}.EditField.Position = [150 pos_bott 80 app.def_height];
                        app.DFstruct.SetPropGUI.param{ii}.EditField.Value=  app.DFstruct.parameters{ii}.val;
                        app.DFstruct.SetPropGUI.param{ii}.EditField.ValueChangedFcn = createCallbackFcn(app, @(app,event) EditField_ValueSetPropDFChanged(app,ii), true);
                end
                
            end
        end
        
        % -- Button pushed function: Button_ApplyChangesDFPushed
        function Button_ApplyChangesDFPushed(app)
            % SHOULD BE A CALL TO A GENERIC FUNCTION
        end
                
        % -- Button pushed function: Button_BrowseSetPropLinOpPushed
        function Button_BrowseSetPropDFPushed(app,index)
            % SHOULD BE A CALL TO A GENERIC FUNCTION
        end
        
        % -- Button pushed function: EditField_ValueSetPropDFChanged
        function EditField_ValueSetPropDFChanged(app,index)
            % SHOULD BE A CALL TO A GENERIC FUNCTION
        end
        
        % -- Delete hierarchy of regularizations
        function deleteHierarchyRegul(app)
            
            % Minimum text before adding reg terms
            app.costText = ['C(x) = ' app.DFstruct.notation '(x,y)'];
            
            % Loop over all the selected regularizations
            % Note: 1st entry of RegulCell is data-fidelity
            for idx=1:length(app.RegulCell)
                delete(app.RegulCell{idx}.label);
                delete(app.RegulCell{idx}.butDel);
                delete(app.RegulCell{idx}.ddlabel);
            end
        end
        
        % -- Create hierarchy of regularizations
        function createHierarchyRegul(app)
            
            for idx=1:length(app.RegulCell)
                
                % Position info
                pos_bott      = app.pos_bott_init-(idx+1)*app.incr_height;
                
                % Add Regul name info (for the selection box below)
                app.RegulCell{idx}.label = uilabel(app.CostFunction);
                app.RegulCell{idx}.label.Text = [app.RegulCell{idx}.name, ' regularization'];
                app.RegulCell{idx}.label.Position = [app.pos_left+40 pos_bott-20 app.def_width*8 app.def_height];
                
                % Create label of drop-down component (for the selection box below)
                app.RegulCell{idx}.ddlabel = uilabel(app.CostFunction);
                app.RegulCell{idx}.ddlabel.Position = [app.pos_left pos_bott-20 app.def_width*1.5 app.def_height];
                app.RegulCell{idx}.ddlabel.FontWeight = 'bold';
                app.RegulCell{idx}.ddlabel.FontSize = 16;
                app.RegulCell{idx}.ddlabel.Text = ['R' char(8320+idx) ' : '];
                
                % Create parameters button
                app.DFstruct.butProp                 = uibutton(app.CostFunction, 'push');
                app.DFstruct.butProp.Text            = 'Set Parameters';
                app.DFstruct.butProp.FontWeight      = 'bold';
                app.DFstruct.butProp.Position        = [app.pos_left+230 pos_bott-20 app.def_width*3.2 app.def_height];
                app.DFstruct.butProp.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_PropRegulButtonPushed(app,idx), true);
                
                % Create delete button (for the selection box below)
                app.RegulCell{idx}.butDel = uibutton(app.CostFunction, 'push');
                app.RegulCell{idx}.butDel.Text = '';
                app.RegulCell{idx}.butDel.Icon = 'trashIcon.png';
                app.RegulCell{idx}.butDel.Position = [app.pos_left+340 pos_bott-20 app.def_width*0.8 app.def_height];
                app.RegulCell{idx}.butDel.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_DelRegulButtonPushed(app,idx), true);
                
                % Update text for concatenation of regs (for the text box above)
                app.costText = [app.costText ' + ' char(955) char(8320+idx) 'R' char(8320+idx) '(x)'];
            end
            
            % Update text for concatenation of linOps (for the text box above)
            app.TextArea_CostFunction.Value = {app.costText};
            
        end
        
    end
    
    % ======================================
    %           OPTIMIZATION PANEL
    % ======================================
    methods (Access = private)


    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % GENERAL ---------------
            app.SetParamsFigs=cell(1,app.maxLinOp+1+app.maxRegul);
            
            % MAIN PANEL --------------
            InitializeMainPanel(app);
            
            % FORWARD MODEL PANEL ------------------
            InitializeForwardModelPanel(app);
            
            % COST FUNCTION PANEL ------------------
            InitializeCostFunctionPanel(app);
            
        end

        % Button pushed function: ButtonSelectLinOp
        function ButtonPushed_SelectLinOp(app, event)
            % Delete previously displayed text in the hierarchy box
            HideHierarchyLinOps(app);
            
            % Add linOp name info
            idxLinOp=length(app.LinOpCell)+1;
            app.LinOpCell{idxLinOp}.name=app.DropDownLinOp.Value;
            
            % Get parameters from comments
            text=splitlines(fileread(['LinOp',app.LinOpCell{idxLinOp}.name,'.m']));
            splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUInotation'))},'-','split'); % Get notation
            app.LinOpCell{idxLinOp}.notation=splitxt{2};
            splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUIcall'))},'-','split'); % Get call line
            app.LinOpCell{idxLinOp}.call=splitxt{2};
            ll=text(~cellfun(@isempty, regexp(text, 'GUIparam'))); % Get parameters
            for ii=1:length(ll)
                splitxt=regexp(ll{ii},'-','split');
                app.LinOpCell{idxLinOp}.parameters{ii}.name=splitxt{2};
                app.LinOpCell{idxLinOp}.parameters{ii}.type=splitxt{3};
                if strcmp('[]',splitxt{4}), text=''; else, text=splitxt{4}; end
                app.LinOpCell{idxLinOp}.parameters{ii}.val=text;
                app.LinOpCell{idxLinOp}.parameters{ii}.info=splitxt{5};
            end
            
            % Display updated text in the hierarchy box
            ShowHierarchyLinOps(app);
            
            % Disable addLinOp button if max number of linOps reached
            if length(app.LinOpCell)>=app.maxLinOp
                app.ButtonSelectLinOp.Enable = 'off';
            end            
        end

        % Button pushed function: ButtonSeeDocLinOp, 
        % Button_SeeDocDataFidelity, Button_SeeDocRegul
        function ButtonPushed_SeeDoc(app, event)
            tag=event.Source.Tag;
            switch tag
                case 'LinOp'
                    ll = ['linop.html#linop' lower(app.DropDownLinOp.Value)];
                case 'DF'
                    idx = strcmp(app.DropDown_SelectDataFidelity.Items,app.DropDown_SelectDataFidelity.Value);
                    ll = ['cost.html#cost' lower(app.dfListCode{idx})];
                case 'Regul'
                    % SEE WHAT TO DO
                    % ALSO THE CASE IN THE FIGURE SET PARAMETERS
            end
            web([app.doc_weblink ll], '-notoolbar');
        end

        % Button pushed function: Button_Add_DataFidelity
        function ButtonPushed_AddDataFidelity(app, event)
            
            
            % Add code name of data-fidelity term
            for i=1:length(app.dfListGUI)
                if strcmp(app.DropDown_SelectDataFidelity.Value, app.dfListGUI{i})
                    app.DFstruct.name = app.dfListCode{i};
                end
            end
            
            % Get parameters from comments
            text=splitlines(fileread(['Cost',app.DFstruct.name,'.m']));
            splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUInotation'))},'-','split'); % Get notation
            app.DFstruct.notation=splitxt{2};
            splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUIcall'))},'-','split'); % Get call line
            app.DFstruct.call=splitxt{2};
            ll=text(~cellfun(@isempty, regexp(text, 'GUIparam'))); % Get parameters
            for ii=1:length(ll)
                splitxt=regexp(ll{ii},'-','split');
                app.DFstruct.parameters{ii}.name=splitxt{2};
                app.DFstruct.parameters{ii}.type=splitxt{3};
                if strcmp('[]',splitxt{4}), text=''; else, text=splitxt{4}; end
                app.DFstruct.parameters{ii}.val=text;
                app.DFstruct.parameters{ii}.info=splitxt{5};
            end
            
            % Display text in the hierarchy box
            createDataFidelity(app);
            
            % Then allow user to select a regularization term
            app.Button_Add_Regul.Enable                = 'on';
            app.Button_SeeDocRegul.Enable             = 'on';
            app.DropDown_Select_Regul.Enable           = 'on';
            app.AddPositivityConstraintCheckBox.Enable = 'on';
            
            % Forbid the user to select an additional DF term
            app.Button_Add_DataFidelity.Enable         = 'off';
            app.DropDown_SelectDataFidelity.Enable    = 'off';
            app.Button_SeeDocDataFidelity.Enable      = 'off';
            
        end

        % Button pushed function: Button_Add_Regul
        function ButtonPushed_AddRegul(app, event)
            % Delete previously displayed text in the hierarchy box
            if ~isempty(app.RegulCell)
                deleteHierarchyRegul(app);
            end
            
            % Add linOp name info
            app.RegulCell{length(app.RegulCell)+1}.name=app.DropDown_Select_Regul.Value;
            
            % Display updated text in the hierarchy box
            createHierarchyRegul(app);
            
            % Disable addLinOp button if max number of linOps reached
            if length(app.RegulCell)>=app.maxRegul
                app.Button_Add_Regul.Enable = 'off';
            end
            
        end

        % Button pushed function: ButtonCheckFwd
        function ButtonPushed_CheckFwdModel(app, event)
            % Number of LinOps
            nLinOps = length(app.LinOpCell);
            
            % Loop over LinOps
            for idx=1:nLinOps
                % LinOp Notation in the script
                Op=['H',num2str(idx)];
                
                % Loop Over parameters of the LinOp
                nlinesParams=length(app.script.params);
                for ii=1:length(app.LinOpCell{idx}.parameters)
                    
                    % Update parameters section
                    if isempty(app.LinOpCell{idx}.parameters{ii}.val)
                        app.script.params{nlinesParams+ii}=[Op,'_',app.LinOpCell{idx}.parameters{ii}.name,' = []'];
                    else
                        typ = app.LinOpCell{idx}.parameters{ii}.type;
                        switch  typ
                            case 'vecInt'
                                app.script.params{nlinesParams+ii}=[Op,'_',app.LinOpCell{idx}.parameters{ii}.name,' = [',app.LinOpCell{idx}.parameters{ii}.val,'];'];
                            case 'file'
                                ext = regexp(app.LinOpCell{idx}.parameters{ii}.val,'\.','split');
                                ext=ext{end};
                                switch ext
                                    case 'tif'
                                    case {'png','jpg'}
                                        app.script.params{nlinesParams+ii}=[Op,app.LinOpCell{idx}.parameters{ii}.name,' = double(imread(',app.LinOpCell{idx}.parameters{ii}.val,'));'];
                                    case 'mat'
                                    otherwise
                                        OpenLog(app,[app.LinOpCell{idx}.parameters{ii}.name,' of ',app.LinOpCell{idx}.name,' : Unknown file extension .',ext,' (Accepted formats: .tif, .png, .jpg, .mat)']);
                                end
                        end
                    end
                    
                    % Update Fwd Model section
                    
                end
            end
            %str=sprintf('%s\n',app.script.params{:})
        end

        % Button pushed function: ButtonSelectPipeline
        function ButtonPushed_SelectPipeline(app, event)
            pp=app.DropDownPipeline.Value;
            HideHierarchyLinOps(app);
            %% TO EXTEND FOR COST AND OPTI
            % delete...
            enable=true;
            switch pp
                case 'New'
                    app.EditFieldPipeline.Editable = 'on';
                    app.EditFieldPipeline.Value = 'MyPipeline';
                    app.pipeline.name=app.EditFieldPipeline.Value;
                    app.TextAreaDescriptionPipeline.Value = 'Describe your pipeline';
                    app.pipeline.dscpt = app.TextAreaDescriptionPipeline.Value;
                    %app.CostCell={};
                    app.LinOpCell={};
                case 'Select a pipeline'
                    app.EditFieldPipeline.Editable = 'off';
                    app.EditFieldPipeline.Value = 'Select a pipeline';
                    app.TextAreaDescriptionPipeline.Value = '';
                    app.DescriptionTextAreaLabel.Enable = 'off';
                    app.TextAreaDescriptionPipeline.Enable = 'off';
                    app.TextAreaDescriptionPipeline.Editable = 'off';
                    app.SaveButton_2.Enable = 'off';
                    %app.CostCell={};
                    app.LinOpCell={};
                    enable=false;
                otherwise
                    cont=true;
                    if strcmp(pp,'Browse')
                        [file,path]=uigetfile();
                        if file~=0
                            Pipeline=load([path,file]);
                        else
                            cont=false;
                            enable=false;
                        end
                    else
                        Pipeline=load(['pipelines/',pp]);
                    end
                    if cont
                        app.EditFieldPipeline.Editable = 'off';
                        app.LinOpCell=Pipeline.LinOpCell;
                        %app.CostCell=Pipeline.CostCell;
                        app.pipeline=Pipeline.pipeline;
                        app.EditFieldPipeline.Value = app.pipeline.name;
                        app.TextAreaDescriptionPipeline.Value = app.pipeline.dscpt;
                    end
            end
            createHierarchyLinOps(app);
            %% TO EXTEND FOR COST AND OPTI
            %createHierarchyRegul(app);
            %createDataFidelity(app);
            if enable
                app.DescriptionTextAreaLabel.Enable = 'on';
                app.TextAreaDescriptionPipeline.Enable = 'on';
                app.TextAreaDescriptionPipeline.Editable = 'on';
                app.ButtonSaveMainPanel.Enable = 'on';
            end
        end

        % Button pushed function: ButtonSaveCostPanel, 
        % ButtonSaveFwdPanel, ButtonSaveMainPanel, ButtonSaveOptiPanel
        function ButtonPushed_Save(app, event)
            % Get the filename and the path to save the pipeline
            [file,path]=uiputfile('.mat','Save pipeline',[app.EditFieldPipeline.Value,'.mat']);
            % Save the properties LinOpCell, RegulCell, DFstruct, and pipeline
            if file~=0
                LinOpCell = app.LinOpCell;
                RegulCell = app.RegulCell;
                DFstruct = app.DFstruct;
                pipeline = app.pipeline;
                save([path,file],'LinOpCell','RegulCell','DFstruct','pipeline');
                clear LinOpCell RegulCell DFstruct pipeline
            end
        end

        % Value changed function: TextAreaDescriptionPipeline
        function ValueChanged_DescriptionPipeline(app, event)
            app.pipeline.dscpt = app.TextAreaDescriptionPipeline.Value;
        end

        % Value changed function: EditFieldPipeline
        function ValueChanged_NamePipeline(app, event)
            app.pipeline.name = app.EditFieldPipeline.Value;
        end

        % Button pushed function: ButtonDeleteLinOp1, 
        % ButtonDeleteLinOp2, ButtonDeleteLinOp3, ButtonDeleteLinOp4, 
        % ButtonDeleteLinOp5, ButtonDeleteLinOp6
        function ButtonPushed_Delete(app, event)
            tag=event.Source.Tag(1:end-1);         % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end)); % Get the index of the object to remove
            switch tag
                case 'LinOp'
                    % Hide Hierarchy LinOps
                    HideHierarchyLinOps(app);
                    % Update LinOpCell and the SetParamsFigs
                    tmp=app.LinOpCell;
                    tmp2=app.SetParamsFigs;
                    app.LinOpCell={};
                    app.SetParamsFigs(1:app.maxLinOp)=cell(1,app.maxLinOp);
                    pos=1;
                    for ii=1:length(tmp)
                        if ii~=idx
                            app.LinOpCell{pos}=tmp{ii};
                            app.SetParamsFigs{pos}=tmp2{ii};
                            pos=pos+1;
                        end
                    end
                    % Show Hierarchy LinOps
                    ShowHierarchyLinOps(app);
                    % Enable addLinOp button if max number of linOps not reached
                    if length(app.LinOpCell)<app.maxLinOp
                        app.ButtonSelectLinOp.Enable = 'on';
                    end
            end
        end

        % Button pushed function: ButtonDownLinOp1, ButtonDownLinOp2, 
        % ButtonDownLinOp3, ButtonDownLinOp4, ButtonDownLinOp5, 
        % ButtonDownLinOp6
        function ButtonPushed_Down(app, event)
            tag=event.Source.Tag(1:end-1);           % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));   % Get the index of the object to move down
            switch tag
                case 'LinOp'
                    % Hide Hierarchy LinOps
                    HideHierarchyLinOps(app);
                    % Reorganize the LinOpCell and the SetParamsFigs
                    tmp=app.LinOpCell{idx+1};
                    app.LinOpCell{idx+1}=app.LinOpCell{idx};
                    app.LinOpCell{idx}=tmp;    
                    tmp=app.SetParamsFigs{idx+1};
                    app.SetParamsFigs{idx+1}=app.SetParamsFigs{idx};
                    app.SetParamsFigs{idx}=tmp;
                    % Show Hierarchy LinOps
                    ShowHierarchyLinOps(app);
            end
        end

        % Button pushed function: ButtonUpLinOp1, ButtonUpLinOp2, 
        % ButtonUpLinOp3, ButtonUpLinOp4, ButtonUpLinOp5, 
        % ButtonUpLinOp6
        function ButtonPushed_Up(app, event)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to move up
            switch tag
                case 'LinOp'
                    % Hide Hierarchy LinOps
                    HideHierarchyLinOps(app); 
                    % Reorganize the LinOpCell and the SetParamsFigs
                    tmp=app.LinOpCell{idx-1};
                    app.LinOpCell{idx-1}=app.LinOpCell{idx};
                    app.LinOpCell{idx}=tmp;
                    tmp=app.SetParamsFigs{idx-1};
                    app.SetParamsFigs{idx-1}=app.SetParamsFigs{idx};
                    app.SetParamsFigs{idx}=tmp;
                    % Show Hierarchy LinOps
                    ShowHierarchyLinOps(app);
            end
        end

        % Button pushed function: ButtonSetParamsLinOp1, 
        % ButtonSetParamsLinOp2, ButtonSetParamsLinOp3, 
        % ButtonSetParamsLinOp4, ButtonSetParamsLinOp5, 
        % ButtonSetParamsLinOp6
        function ButtonPushed_SetParams(app, event)
            app.nbSetParamsFigs=app.nbSetParamsFigs+1; % Increase the counter of opened Set Params Figs
            tag=event.Source.Tag(1:end-1);             % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));     % Get the index of the object whose parameters have to be set
            switch tag
                case 'LinOp'
                    cc=app.LinOpCell{idx}.parameters;
                case 'DF'
                    idx=app.maxLinOp+1;
                case 'Regul'
                    idx=app.maxLinOp+1+idx;     
            end
            if isempty(app.SetParamsFigs{idx})
                % Create Figure
                hh=app.incr_height*(length(cc)+1);
                app.SetParamsFigs{idx}.fig = uifigure;
                app.SetParamsFigs{idx}.fig.Position  = app.GlobalBioImGUIUIFigure.Position  +  [app.GlobalBioImGUIUIFigure.Position(3) app.GlobalBioImGUIUIFigure.Position(4)-hh 0 -app.GlobalBioImGUIUIFigure.Position(4)+hh];
                app.SetParamsFigs{idx}.fig.Name  = 'Set Parameters';
                set(app.SetParamsFigs{idx}.fig,'CloseRequestFcn',[]);  % Disable using the cross to close the window
                
                % Create Name
                app.SetParamsFigs{idx}.title = uilabel(app.SetParamsFigs{idx}.fig);
                app.SetParamsFigs{idx}.title.FontSize = 16;
                app.SetParamsFigs{idx}.title.FontWeight = 'bold';
                app.SetParamsFigs{idx}.title.Position = [19 hh-45 428 38];
                app.SetParamsFigs{idx}.title.Text = getfield(app,['Name',event.Source.Tag],'Text');
                
                % Create See Doc  button
                app.SetParamsFigs{idx}.ButtonSeeDoc = uibutton(app.SetParamsFigs{idx}.fig, 'push');
                app.SetParamsFigs{idx}.ButtonSeeDoc.Position = [280 hh-35 50 20];
                app.SetParamsFigs{idx}.ButtonSeeDoc.Text = 'Doc.';                            
                % TODO : FIX ME
                %app.SetParamsFigs{idx}.ButtonSeeDoc.ButtonPushedFcn = createCallbackFcn(app,@ButtonPushed_SeeDoc, true);
                
                % Create Apply Changes button
                app.SetParamsFigs{idx}.ButtonApplyChanges = uibutton(app.SetParamsFigs{idx}.fig, 'push');
                app.SetParamsFigs{idx}.ButtonApplyChanges.Position = [350 hh-35 100 20];
                app.SetParamsFigs{idx}.ButtonApplyChanges.Text = 'Apply Changes';
                app.SetParamsFigs{idx}.ButtonApplyChanges.ButtonPushedFcn = createCallbackFcn(app,@ButtonPushed_ApplyChanges,true);
                                
                % Display parameters selection
                for ii=1:length(cc)
                    pos_bott      = hh-(ii+0.5)*app.incr_height;
                    % Info Button
                    app.SetParamsFigs{idx}.param{ii}.info = uibutton(app.SetParamsFigs{idx}.fig, 'push');
                    app.SetParamsFigs{idx}.param{ii}.info.Icon = 'infoIcon.png';
                    app.SetParamsFigs{idx}.param{ii}.info.Tooltip = {cc{ii}.info};
                    app.SetParamsFigs{idx}.param{ii}.info.Position = [30 pos_bott 20 app.def_height];
                    app.SetParamsFigs{idx}.param{ii}.info.Text = '';
                    typ=cc{ii}.type;
                    switch  typ
                        case 'file'
                            app.SetParamsFigs{idx}.param{ii}.label = uilabel(app.SetParamsFigs{idx}.fig);
                            app.SetParamsFigs{idx}.param{ii}.label.HorizontalAlignment = 'left';
                            app.SetParamsFigs{idx}.param{ii}.label.Position = [60 pos_bott 80 app.def_height];
                            app.SetParamsFigs{idx}.param{ii}.label.Text = [cc{ii}.name,' : '];
                            app.SetParamsFigs{idx}.param{ii}.TextArea = uitextarea(app.SetParamsFigs{idx}.fig);
                            app.SetParamsFigs{idx}.param{ii}.TextArea.Position = [150 pos_bott 220 app.def_height];
                            text=app.LinOpCell{idx}.parameters{ii}.val;
                            if isempty(text), text='Select a file (.png,.jpg,.tif,.mat)'; end
                            app.SetParamsFigs{idx}.param{ii}.TextArea.Value=text;
                            app.SetParamsFigs{idx}.param{ii}.butBrowse = uibutton(app.SetParamsFigs{idx}.fig, 'push');
                            app.SetParamsFigs{idx}.param{ii}.butBrowse.Text = 'Browse';
                            app.SetParamsFigs{idx}.param{ii}.butBrowse.Position = [380 pos_bott 60 app.def_height];
                            app.SetParamsFigs{idx}.param{ii}.butBrowse.ButtonPushedFcn = createCallbackFcn(app, @(app,event) ButtonPushed_SetParamsFigBrowse(app,event,ii), true);
                            app.SetParamsFigs{idx}.param{ii}.butBrowse.Tag = event.Source.Tag;
                        case 'vecInt'
                            app.SetParamsFigs{idx}.param{ii}.label = uilabel(app.SetParamsFigs{idx}.fig);
                            app.SetParamsFigs{idx}.param{ii}.label.HorizontalAlignment = 'left';
                            app.SetParamsFigs{idx}.param{ii}.label.Position = [60 pos_bott 80 app.def_height];
                            app.SetParamsFigs{idx}.param{ii}.label.Text = [cc{ii}.name,' : '];
                            app.SetParamsFigs{idx}.param{ii}.openBracket = uilabel(app.SetParamsFigs{idx}.fig);
                            app.SetParamsFigs{idx}.param{ii}.openBracket.Position = [140 pos_bott-5 10 app.def_height*1.8];
                            app.SetParamsFigs{idx}.param{ii}.openBracket.Text = '[';
                            app.SetParamsFigs{idx}.param{ii}.openBracket.FontSize = 30;
                            app.SetParamsFigs{idx}.param{ii}.closeBracket = uilabel(app.SetParamsFigs{idx}.fig);
                            app.SetParamsFigs{idx}.param{ii}.closeBracket.Position = [232 pos_bott-5 10 app.def_height*1.8];
                            app.SetParamsFigs{idx}.param{ii}.closeBracket.Text = ']';
                            app.SetParamsFigs{idx}.param{ii}.closeBracket.FontSize = 30;
                            app.SetParamsFigs{idx}.param{ii}.EditField = uieditfield(app.SetParamsFigs{idx}.fig,'text');
                            app.SetParamsFigs{idx}.param{ii}.EditField.Position = [150 pos_bott 80 app.def_height];
                            app.SetParamsFigs{idx}.param{ii}.EditField.Value=  cc{ii}.val;
                            app.SetParamsFigs{idx}.param{ii}.EditField.ValueChangedFcn = createCallbackFcn(app, @(app,event) ValueChanged_SetParamsFigEdit(app,event,ii), true);
                            app.SetParamsFigs{idx}.param{ii}.EditField.Tag = event.Source.Tag;
                    end         
                end
            else
                % Show figure (already created)
                app.SetParamsFigs{idx}.fig.Visible='on';pause(0.1);
            end
            
            % Set Tags
            app.SetParamsFigs{idx}.ButtonApplyChanges.Tag=event.Source.Tag;
            app.SetParamsFigs{idx}.ButtonSeeDoc.Tag = event.Source.Tag;
            for ii=1:length(cc)
                typ=cc{ii}.type;
                switch  typ
                    case 'file'
                        app.SetParamsFigs{idx}.param{ii}.butBrowse.Tag = event.Source.Tag;
                    case 'vecInt'
                        app.SetParamsFigs{idx}.param{ii}.EditField.Tag = event.Source.Tag;
                        app.SetParamsFigs{idx}.param{ii}.EditField.Editable='on';
                end
            end
            
            % Disable buttons Up/Down/Delete
            app.ButtonSelectLinOp.Enable='off';
            app = setfield(app,['ButtonSetParams',event.Source.Tag],'Enable','off');
            for ii=1:length(app.LinOpCell)
                app = setfield(app,['ButtonUp',tag,num2str(ii)],'Enable','off');
                app = setfield(app,['ButtonDown',tag,num2str(ii)],'Enable','off');
                app = setfield(app,['ButtonDelete',tag,num2str(ii)],'Enable','off');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GlobalBioImGUIUIFigure = uifigure;
            app.GlobalBioImGUIUIFigure.Name = 'GlobalBioIm - GUI';
            app.TabGroup = uitabgroup(app.GlobalBioImGUIUIFigure);
            app.Main = uitab(app.TabGroup);
            app.Main.Title = 'Main';
            app.LabelGlobalBioIm = uilabel(app.Main);
            app.LabelGlobalBioIm.Text = 'GlobalBioIm';
            app.ButtonSaveMainPanel = uibutton(app.Main, 'push');
            app.ButtonSaveMainPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.ButtonSaveMainPanel.Enable = 'off';
            app.ButtonSaveMainPanel.Text = 'Save';
            app.ButtonSelectPipeline = uibutton(app.Main, 'push');
            app.ButtonSelectPipeline.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SelectPipeline, true);
            app.ButtonSelectPipeline.Text = 'Select';
            app.DropDownPipeline = uidropdown(app.Main);
            app.PipelineEditFieldLabel = uilabel(app.Main);
            app.PipelineEditFieldLabel.Text = 'Pipeline';
            app.EditFieldPipeline = uieditfield(app.Main, 'text');
            app.EditFieldPipeline.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_NamePipeline, true);
            app.EditFieldPipeline.Editable = 'off';
            app.EditFieldPipeline.Value = 'Select a pipeline';
            app.DescriptionTextAreaLabel = uilabel(app.Main);
            app.DescriptionTextAreaLabel.Enable = 'off';
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.TextAreaDescriptionPipeline = uitextarea(app.Main);
            app.TextAreaDescriptionPipeline.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_DescriptionPipeline, true);
            app.TextAreaDescriptionPipeline.Enable = 'off';
            app.LabelCopyright = uilabel(app.Main);
            app.LabelCopyright.Text = 'Copyright (C) 2019 - The GlobalBioIm Team';
            app.ForwardModel = uitab(app.TabGroup);
            app.ForwardModel.Title = 'Forward Model';
            app.TextAreaForwardModel = uitextarea(app.ForwardModel);
            app.LabelTextArea = uilabel(app.ForwardModel);
            app.LabelTextArea.Text = 'Forward Model';
            app.ButtonSelectLinOp = uibutton(app.ForwardModel, 'push');
            app.ButtonSelectLinOp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SelectLinOp, true);
            app.ButtonSelectLinOp.Text = 'Select';
            app.ButtonSeeDocLinOp = uibutton(app.ForwardModel, 'push');
            app.ButtonSeeDocLinOp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SeeDoc, true);
            app.ButtonSeeDocLinOp.Text = 'Doc.';
            app.OperatorLabel = uilabel(app.ForwardModel);
            app.OperatorLabel.Text = 'Operator';
            app.DropDownLinOp = uidropdown(app.ForwardModel);
            app.DropDownLinOp.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDownLinOp.Value = 'Test';
            app.PanelHierarchyLinOp = uipanel(app.ForwardModel);
            app.PanelHierarchyLinOp.Title = 'Hierarchy of Selected Operators';
            app.NotationLinOp1 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp1.Text = 'H1 :';
            app.NameLinOp1 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp1.Text = 'LinOp1';
            app.ButtonSetParamsLinOp1 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp1.Text = 'Set Parameters';
            app.ButtonUpLinOp1 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp1.Icon = 'upArrow.png';
            app.ButtonUpLinOp1.Text = '';
            app.ButtonDownLinOp1 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp1.Icon = 'downArrow.png';
            app.ButtonDownLinOp1.Text = '';
            app.ButtonDeleteLinOp1 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp1.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp1.Text = '';
            app.NotationLinOp2 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp2.Text = 'H2 :';
            app.NameLinOp2 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp2.Text = 'LinOp2';
            app.ButtonSetParamsLinOp2 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp2.Text = 'Set Parameters';
            app.ButtonUpLinOp2 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp2.Icon = 'upArrow.png';
            app.ButtonUpLinOp2.Text = '';
            app.ButtonDownLinOp2 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp2.Icon = 'downArrow.png';
            app.ButtonDownLinOp2.Text = '';
            app.ButtonDeleteLinOp2 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp2.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp2.Text = '';
            app.NotationLinOp3 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp3.Text = 'H3 :';
            app.NameLinOp3 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp3.Text = 'LinOp3';
            app.ButtonSetParamsLinOp3 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp3.Text = 'Set Parameters';
            app.ButtonUpLinOp3 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp3.Icon = 'upArrow.png';
            app.ButtonUpLinOp3.Text = '';
            app.ButtonDownLinOp3 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp3.Icon = 'downArrow.png';
            app.ButtonDownLinOp3.Text = '';
            app.ButtonDeleteLinOp3 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp3.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp3.Text = '';
            app.NotationLinOp4 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp4.Text = 'H4 :';
            app.NameLinOp4 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp4.Text = 'LinOp4';
            app.ButtonSetParamsLinOp4 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp4.Text = 'Set Parameters';
            app.ButtonUpLinOp4 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp4.Icon = 'upArrow.png';
            app.ButtonUpLinOp4.Text = '';
            app.ButtonDownLinOp4 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp4.Icon = 'downArrow.png';
            app.ButtonDownLinOp4.Text = '';
            app.ButtonDeleteLinOp4 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp4.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp4.Text = '';
            app.NotationLinOp5 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp5.Text = 'H5 :';
            app.NameLinOp5 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp5.Text = 'LinOp5';
            app.ButtonSetParamsLinOp5 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp5.Text = 'Set Parameters';
            app.ButtonUpLinOp5 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp5.Icon = 'upArrow.png';
            app.ButtonUpLinOp5.Text = '';
            app.ButtonDownLinOp5 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp5.Icon = 'downArrow.png';
            app.ButtonDownLinOp5.Text = '';
            app.ButtonDeleteLinOp5 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp5.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp5.Text = '';
            app.NotationLinOp6 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp6.Text = 'H6 :';
            app.NameLinOp6 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp6.Text = 'LinOp6';
            app.ButtonSetParamsLinOp6 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp6.Text = 'Set Parameters';
            app.ButtonUpLinOp6 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp6.Icon = 'upArrow.png';
            app.ButtonUpLinOp6.Text = '';
            app.ButtonDownLinOp6 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp6.Icon = 'downArrow.png';
            app.ButtonDownLinOp6.Text = '';
            app.ButtonDeleteLinOp6 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp6.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp6.Text = '';
            app.ButtonInfoLinOp = uibutton(app.ForwardModel, 'push');
            app.ButtonInfoLinOp.Icon = 'infoIcon.png';
            app.ButtonInfoLinOp.Text = '';
            app.ButtonCheckFwd = uibutton(app.ForwardModel, 'push');
            app.ButtonCheckFwd.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_CheckFwdModel, true);
            app.ButtonCheckFwd.Text = 'Check Your Forward Model (Recommended)';
            app.ButtonSaveFwdPanel = uibutton(app.ForwardModel, 'push');
            app.ButtonSaveFwdPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.ButtonSaveFwdPanel.Text = 'Save';
            app.CostFunction = uitab(app.TabGroup);
            app.CostFunction.Title = 'Cost Function';
            app.TextArea_CostFunction = uitextarea(app.CostFunction);
            app.CostFunctionLabel = uilabel(app.CostFunction);
            app.CostFunctionLabel.Text = 'Cost Function';
            app.Button_Add_DataFidelity = uibutton(app.CostFunction, 'push');
            app.Button_Add_DataFidelity.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_AddDataFidelity, true);
            app.Button_Add_DataFidelity.Text = 'Select';
            app.Button_SeeDocDataFidelity = uibutton(app.CostFunction, 'push');
            app.Button_SeeDocDataFidelity.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SeeDoc, true);
            app.Button_SeeDocDataFidelity.Text = 'Doc.';
            app.DatafidelityLabel = uilabel(app.CostFunction);
            app.DatafidelityLabel.Text = 'Data-fidelity';
            app.DropDown_SelectDataFidelity = uidropdown(app.CostFunction);
            app.DropDown_SelectDataFidelity.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDown_SelectDataFidelity.Value = 'Test';
            app.FMPanel_CostFunction = uipanel(app.CostFunction);
            app.FMPanel_CostFunction.Title = 'Selected Regularization Terms';
            app.Button_Add_Regul = uibutton(app.CostFunction, 'push');
            app.Button_Add_Regul.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_AddRegul, true);
            app.Button_Add_Regul.Text = 'Select';
            app.Button_SeeDocRegul = uibutton(app.CostFunction, 'push');
            app.Button_SeeDocRegul.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SeeDoc, true);
            app.Button_SeeDocRegul.Text = 'Doc.';
            app.RegularizerLabel = uilabel(app.CostFunction);
            app.RegularizerLabel.Text = 'Regularizer';
            app.DropDown_Select_Regul = uidropdown(app.CostFunction);
            app.DropDown_Select_Regul.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDown_Select_Regul.Value = 'Test';
            app.AddPositivityConstraintCheckBox = uicheckbox(app.CostFunction);
            app.AddPositivityConstraintCheckBox.Text = ' Add Positivity Constraint';
            app.FMPanel_CostFunction_2 = uipanel(app.CostFunction);
            app.FMPanel_CostFunction_2.Title = 'Selected Data-Fidelity Term';
            app.Button = uibutton(app.CostFunction, 'push');
            app.Button.Icon = 'infoIcon.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.CostFunction, 'push');
            app.Button_2.Icon = 'infoIcon.png';
            app.Button_2.Text = '';
            app.ButtonSaveCostPanel = uibutton(app.CostFunction, 'push');
            app.ButtonSaveCostPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.ButtonSaveCostPanel.Text = 'Save';
            app.Optimization = uitab(app.TabGroup);
            app.Optimization.Title = 'Optimization';
            app.FMPanel_CostFunction_3 = uipanel(app.Optimization);
            app.FMPanel_CostFunction_3.Title = 'Selected Algorithm';
            app.Button_Add_Regul_2 = uibutton(app.Optimization, 'push');
            app.Button_Add_Regul_2.Text = 'Select';
            app.Button_SeeDoc_Regul_2 = uibutton(app.Optimization, 'push');
            app.Button_SeeDoc_Regul_2.Text = 'Doc.';
            app.AlgorithmLabel = uilabel(app.Optimization);
            app.AlgorithmLabel.Text = 'Algorithm';
            app.DropDown_Select_Regul_2 = uidropdown(app.Optimization);
            app.DropDown_Select_Regul_2.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDown_Select_Regul_2.Value = 'Test';
            app.Button_4 = uibutton(app.Optimization, 'push');
            app.Button_4.Icon = 'infoIcon.png';
            app.Button_4.Text = '';
            app.RUNButton = uibutton(app.Optimization, 'push');
            app.RUNButton.Text = 'RUN';
            app.GenerateScriptButton = uibutton(app.Optimization, 'push');
            app.GenerateScriptButton.Text = 'Generate Script';
            app.RecapofAllSelectedParametersButton = uibutton(app.Optimization, 'push');
            app.RecapofAllSelectedParametersButton.Text = 'Recap of All Selected Parameters';
            app.DisplaythecostfunctionevolutionCheckBox = uicheckbox(app.Optimization);
            app.DisplaythecostfunctionevolutionCheckBox.Text = ' Display the cost-function evolution';
            app.FMPanel_CostFunction_4 = uipanel(app.Optimization);
            app.FMPanel_CostFunction_4.Title = 'Relevant References';
            app.ButtonSaveOptiPanel = uibutton(app.Optimization, 'push');
            app.ButtonSaveOptiPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.ButtonSaveOptiPanel.Text = 'Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_GlobalBioIm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GlobalBioImGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GlobalBioImGUIUIFigure)
        end
    end
end